classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectLapDropDown       matlab.ui.control.DropDown
        SelectLapDropDownLabel  matlab.ui.control.Label
        LoadFileButton          matlab.ui.control.StateButton
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        appData % Description
        apptemp
        apploc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown.Items=0;
        end

        % Value changed function: LoadFileButton, SelectLapDropDown
        function LoadFileButtonValueChanged(app, event)
            
            value = app.LoadFileButton.Value;
            [file,path] = uigetfile;
            Data = uiimport(file);
            maximo=max(Data.Brake_Pos.Value);
            normBrake=Data.Brake_Pos.Value/maximo;
            hold (app.UIAxes,"on");
            axis(app.UIAxes,([min(Data.Car_Coord_X.Value)-20 max(Data.Car_Coord_X.Value)+20 min(Data.Car_Coord_Y.Value)-20 max(Data.Car_Coord_Y.Value)+20]))
            for k = 1:length(Data.Car_Coord_Z.Time)-1
                 plot(app.UIAxes,[Data.Car_Coord_X.Value(k),Data.Car_Coord_X.Value(k+1)],[Data.Car_Coord_Y.Value(k),Data.Car_Coord_Y.Value(k+1)],'Color',[normBrake(k) 1-normBrake(k) 0],LineWidth=2.5);
            end
            plot(app.UIAxes2, Data.Brake_Pos.Time, Data.Brake_Pos.Value);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'state');
            app.LoadFileButton.ValueChangedFcn = createCallbackFcn(app, @LoadFileButtonValueChanged, true);
            app.LoadFileButton.Text = 'Load File';
            app.SelectLapDropDownLabel = uilabel(app.UIFigure);
            app.SelectLapDropDownLabel.Text = 'Select Lap';
            app.SelectLapDropDown = uidropdown(app.UIFigure);
            app.SelectLapDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadFileButtonValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end