classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        LoadFileButton            matlab.ui.control.StateButton
        LapPanel                  matlab.ui.container.Panel
        SelectLapDropDown         matlab.ui.control.DropDown
        SelectLapDropDownLabel    matlab.ui.control.Label
        SelectmapDataButtonGroup  matlab.ui.container.ButtonGroup
        GroundSpeedButton         matlab.ui.control.RadioButton
        BrakeIntensityButton      matlab.ui.control.RadioButton
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        appData % Description
        apptemp
        apploc
        Data
        selectedLap
        selectedButton
    end
    
    methods (Access = private)
        
        function results = PlotData(app)

            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            maxBrake=max(app.Data.Brake_Pos.Value);
            normBrake=app.Data.Brake_Pos.Value/maxBrake;
            maxSpeed=max(app.Data.Ground_Speed.Value);
            normSpeed = app.Data.Ground_Speed.Value/maxSpeed;
            hold (app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            hold(app.UIAxes3,"on");

            selected_lap = str2double(app.selectedLap);
            shift = 0;

            if(selected_lap > 0)
                t_shift = time_shift(app);
                shift = t_shift(selected_lap);
            end
            
            pbaspect(app.UIAxes,[1 1 1])
            axis(app.UIAxes,([min(app.Data.Car_Coord_X.Value)-20 max(app.Data.Car_Coord_X.Value)+20 min(app.Data.Car_Coord_Y.Value)-20 max(app.Data.Car_Coord_Y.Value)+20]))
            
       
            for k = 1:length(app.Data.Car_Coord_Z.Time)-1
                if(app.selectedButton=="Brake Intensity")
                    if(app.Data.Session_Lap_Count.Value(k)==(selected_lap))  
                        plot(app.UIAxes,[app.Data.Car_Coord_X.Value(k),app.Data.Car_Coord_X.Value(k+1)],[app.Data.Car_Coord_Y.Value(k),app.Data.Car_Coord_Y.Value(k+1)],'Color',[normBrake(k) 1-normBrake(k) 0],LineWidth=2.5);
                    end
                end
                if(app.selectedButton=="Ground Speed")
                    if(app.Data.Session_Lap_Count.Value(k)==(selected_lap))  
                        plot(app.UIAxes,[app.Data.Car_Coord_X.Value(k),app.Data.Car_Coord_X.Value(k+1)],[app.Data.Car_Coord_Y.Value(k),app.Data.Car_Coord_Y.Value(k+1)],'Color',[1-normSpeed(k) 0 normSpeed(k)],LineWidth=2.5);
                    end
                end                 
                if(app.Data.Session_Lap_Count.Value(k)==(selected_lap)) 
                        plot(app.UIAxes2,[app.Data.Brake_Pos.Time(k) - shift,app.Data.Brake_Pos.Time(k+1)-shift],[app.Data.Brake_Pos.Value(k),app.Data.Brake_Pos.Value(k+1)],'Color',[normBrake(k) 1-normBrake(k) 0])
                        plot(app.UIAxes3,[app.Data.Ground_Speed.Time(k)-shift,app.Data.Ground_Speed.Time(k+1)-shift],[app.Data.Ground_Speed.Value(k),app.Data.Ground_Speed.Value(k+1)],'Color',[1-normSpeed(k) 0 normSpeed(k)])                
                end 
                
             end
            title(app.UIAxes,(num2str(str2double(app.selectedLap)+1)+" lap"))

            app.LoadFileButton.Text = "Load File";

        end

        
        function times = time_shift(app)
            laps = unique(app.Data.Session_Lap_Count.Value);
            indexs= [0];

            for lap = laps
                indexs(end+1)  = find(app.Data.Session_Lap_Count.Value == lap, 1, 'last');
            end
            
            indexs = indexs(2:end);
            times = [0];

            for index = indexs
                times(end+1) = app.Data.Session_Lap_Count.Time(index);
            end

            times = times(2:end);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.SelectLapDropDown.Items = ["Selec data first"];
              app.selectedLap = {'0'};
              app.selectedButton = "Brake Intensity";
             
              app.UIAxes.Visible = 'off';
              title(app.UIAxes,"Brake Intensity");
  

              title(app.UIAxes2,"Brake Intensity");
              xlabel(app.UIAxes2,"Time [s]");
              ylabel(app.UIAxes2,"Percentage of Brake Pressed [%]");
              grid(app.UIAxes2,"on");
              grid(app.UIAxes2,"minor");

              title(app.UIAxes3,"Ground Speed");
              xlabel(app.UIAxes3,"Time [s]");
              ylabel(app.UIAxes3,"Speed [Km/h]");
              grid(app.UIAxes3,"on");
              grid(app.UIAxes3,"minor");

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
        end

        % Value changed function: LoadFileButton
        function LoadFileButtonValueChanged(app, event)
            value = app.LoadFileButton.Value;
            [file,path] = uigetfile;
            app.LoadFileButton.Text = "Loading file...";
            app.Data = uiimport(file);
            laps = unique(app.Data.Session_Lap_Count.Value);

            time_shift(app)

            cell_array = cell(size(laps));
            for i=1:length(laps)
                cell_array{i} = num2str(laps(i));
            end
            app.SelectLapDropDown.Items = cell_array;
            PlotData(app);
        end

        % Value changed function: SelectLapDropDown
        function SelectLapDropDownValueChanged(app, event)
            app.selectedLap = app.SelectLapDropDown.Value;
            PlotData(app);
        end

        % Selection changed function: SelectmapDataButtonGroup
        function SelectmapDataButtonGroupSelectionChanged(app, event)
            value3 = app.SelectmapDataButtonGroup.SelectedObject;
            switch value3.Text
                case "Brake Intensity"
                    app.selectedButton = "Brake Intensity";
                case "Ground Speed"
                    app.selectedButton ="Ground Speed";
                otherwise
                    app.selectedButton = "0";
            end
            PlotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.LapPanel = uipanel(app.UIFigure);
            app.LapPanel.Title = 'Lap';
            app.UIAxes = uiaxes(app.LapPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SelectmapDataButtonGroup = uibuttongroup(app.LapPanel);
            app.SelectmapDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectmapDataButtonGroupSelectionChanged, true);
            app.SelectmapDataButtonGroup.Title = 'Select map Data';
            app.BrakeIntensityButton = uiradiobutton(app.SelectmapDataButtonGroup);
            app.BrakeIntensityButton.Text = 'Brake Intensity';
            app.BrakeIntensityButton.Value = true;
            app.GroundSpeedButton = uiradiobutton(app.SelectmapDataButtonGroup);
            app.GroundSpeedButton.Text = 'Ground Speed';
            app.SelectLapDropDownLabel = uilabel(app.LapPanel);
            app.SelectLapDropDownLabel.Text = 'Select Lap';
            app.SelectLapDropDown = uidropdown(app.LapPanel);
            app.SelectLapDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectLapDropDownValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'state');
            app.LoadFileButton.ValueChangedFcn = createCallbackFcn(app, @LoadFileButtonValueChanged, true);
            app.LoadFileButton.Text = 'Load File';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Img', 'logo_eesc.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Img', 'usp-logo-png.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end